classdef mainfrm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        NoiseReductionButton    matlab.ui.control.Button
        ButtonGroup             matlab.ui.container.ButtonGroup
        PulseSignalButton       matlab.ui.control.RadioButton
        SquareWaveSignalButton  matlab.ui.control.RadioButton
        SineWaveSignalButton    matlab.ui.control.RadioButton
        EnableNoiseCheckBox     matlab.ui.control.CheckBox
        GenerateSignalsButton   matlab.ui.control.Button
        TabGroup                matlab.ui.container.TabGroup
        Tab                     matlab.ui.container.Tab
        Tab_3                   matlab.ui.container.Tab
        Tab_2                   matlab.ui.container.Tab
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateSignalsButton
        function GenerateSignalsButtonPushed(app, event)
            % 生成含有高斯白噪声的正弦信号
            fs = 1000; % 采样频率
            t = 0:1/fs:1-1/fs; % 时间序列
            f = 10; % 正弦信号频率
            A = 1; % 正弦信号幅度
            x = A*sin(2*pi*f*t); % 生成正弦信号
            noise = 0.1*randn(size(x)); % 生成高斯白噪声
            y = x + noise; % 添加噪声
            
            % 低通滤波
            Fc = 50; % 截止频率
            [b,a] = butter(4, Fc/(fs/2), 'low'); % 4阶Butterworth低通滤波器
            yf = filtfilt(b, a, y); % 应用滤波器
            
            % 可视化展示
            figure;
            plot(app.UIAxes,t, y, 'b', t, yf, 'r');
            xlabel(app.UIAxes,'Time (s)');
            ylabel(app.UIAxes,'Amplitude');
            title(app.UIAxes,'Original Signal and Filtered Signal');
            legend(app.UIAxes,'Original Signal', 'Filtered Signal');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '信号';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '噪声';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '滤波';
            app.GenerateSignalsButton = uibutton(app.UIFigure, 'push');
            app.GenerateSignalsButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateSignalsButtonPushed, true);
            app.GenerateSignalsButton.Text = 'GenerateSignals';
            app.EnableNoiseCheckBox = uicheckbox(app.UIFigure);
            app.EnableNoiseCheckBox.Text = 'EnableNoise';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = '信号类型';
            app.SineWaveSignalButton = uiradiobutton(app.ButtonGroup);
            app.SineWaveSignalButton.Text = 'SineWaveSignal';
            app.SineWaveSignalButton.Value = true;
            app.SquareWaveSignalButton = uiradiobutton(app.ButtonGroup);
            app.SquareWaveSignalButton.Text = 'SquareWaveSignal';
            app.PulseSignalButton = uiradiobutton(app.ButtonGroup);
            app.PulseSignalButton.Text = 'PulseSignal';
            app.NoiseReductionButton = uibutton(app.UIFigure, 'push');
            app.NoiseReductionButton.Text = 'NoiseReduction';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainfrm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end